classdef Reading_Data_from_Excel_to_Table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ReadDataButton  matlab.ui.control.Button
        UITable         matlab.ui.control.Table
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadDataButton
        function ReadDataButtonPushed(app, event)
            data_excel = readtable("Reading_Data_from_Excel_to_Table.xlsx", "Sheet", 1);

            app.UITable.Data = data_excel;
            data_excel.Properties.VariableNames{1} = 'X Data';
            data_excel.Properties.VariableNames{2} = 'Y Data';
            app.UITable.ColumnName = data_excel.Properties.VariableNames;

            x = table2array(data_excel(:,"X Data"));
            y = table2array(data_excel(:,"Y Data"));
            plot(app.UIAxes,y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'y = x^2')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.ReadDataButton = uibutton(app.UIFigure, 'push');
            app.ReadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ReadDataButtonPushed, true);
            app.ReadDataButton.Text = 'Read Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reading_Data_from_Excel_to_Table

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end