classdef Simple_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NewtonsCalculaterLabel  matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        WeightNEditField        matlab.ui.control.NumericEditField
        WeightNEditFieldLabel   matlab.ui.control.Label
        Accms2EditField         matlab.ui.control.NumericEditField
        AccLabel                matlab.ui.control.Label
        MasskgEditField         matlab.ui.control.NumericEditField
        MasskgEditFieldLabel    matlab.ui.control.Label
        Lamp                    matlab.ui.control.Lamp
        CalculateButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            mass = app.MasskgEditField.Value;
            acc = app.Accms2EditField.Value;

            force = mass*acc;
            app.WeightNEditField.Value = force;

            if force >= 0 && force <= 500
                app.Lamp.Color = [0.00,1.00,0.00];
            elseif force > 500 && force <= 800
                app.Lamp.Color = [1.00,1.00,0.07];
            else
                app.Lamp.Color = [1.00,0.00,0.00];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Lamp = uilamp(app.Panel);
            app.MasskgEditFieldLabel = uilabel(app.Panel);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.Panel, 'numeric');
            app.AccLabel = uilabel(app.Panel);
            app.AccLabel.Text = 'Acc (m/s^2)';
            app.Accms2EditField = uieditfield(app.Panel, 'numeric');
            app.WeightNEditFieldLabel = uilabel(app.Panel);
            app.WeightNEditFieldLabel.Text = 'Weight (N)';
            app.WeightNEditField = uieditfield(app.Panel, 'numeric');
            app.NewtonsCalculaterLabel = uilabel(app.UIFigure);
            app.NewtonsCalculaterLabel.Text = 'Newton''s Calculater';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simple_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end