classdef knob_discrete_Knob < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        EditField_3  matlab.ui.control.EditField
        Knob2        matlab.ui.control.DiscreteKnob
        EditField    matlab.ui.control.NumericEditField
        Knob         matlab.ui.control.Knob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            
            app.EditField.Value = value;
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            changingValue = event.Value;

            app.EditField.Value = changingValue;
        end

        % Value changed function: Knob2
        function Knob2ValueChanged(app, event)
            value = app.Knob2.Value;
            if strcmp(value,'Off')
                app.EditField_3.Value = "Off";
            elseif strcmp(value,'Low')
                app.EditField_3.Value = "Low";
            elseif strcmp(value,'Medium')
                app.EditField_3.Value = "Medium";
            else
                app.EditField_3.Value = "High";
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)

        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Knob2 = uiknob(app.UIFigure, 'discrete');
            app.Knob2.ValueChangedFcn = createCallbackFcn(app, @Knob2ValueChanged, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EditField_3.Value = 'Off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = knob_discrete_Knob

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end